classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        XSwitchLabel              matlab.ui.control.Label
        XSwitch                   matlab.ui.control.Switch
        UIAxes                    matlab.ui.control.UIAxes
        YSwitchLabel              matlab.ui.control.Label
        YSwitch                   matlab.ui.control.Switch
        Z1SwitchLabel             matlab.ui.control.Label
        Z1Switch                  matlab.ui.control.Switch
        Z2SwitchLabel             matlab.ui.control.Label
        Z2Switch                  matlab.ui.control.Switch
        AllSwitchLabel            matlab.ui.control.Label
        AllSwitch                 matlab.ui.control.ToggleSwitch
        UIAxes2                   matlab.ui.control.UIAxes
        ActionsButtonGroup        matlab.ui.container.ButtonGroup
        LocatePetalButton         matlab.ui.control.ToggleButton
        LocateHybridsButton       matlab.ui.control.ToggleButton
        GluedispensingButton      matlab.ui.control.ToggleButton
        HomingButton              matlab.ui.control.ToggleButton
        MovementButtonGroup       matlab.ui.container.ButtonGroup
        SimulationButton          matlab.ui.control.RadioButton
        ManualButton              matlab.ui.control.RadioButton
        AutoButton                matlab.ui.control.RadioButton
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        Z1EditFieldLabel          matlab.ui.control.Label
        Z1EditField               matlab.ui.control.NumericEditField
        Z2EditFieldLabel          matlab.ui.control.Label
        Z2EditField               matlab.ui.control.NumericEditField
        EditFieldLabel            matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        ConnectGantryCheckBox     matlab.ui.control.CheckBox
        ConnectDispenserCheckBox  matlab.ui.control.CheckBox
        ConnectCameraCheckBox     matlab.ui.control.CheckBox
        ConnectJoystickCheckBox   matlab.ui.control.CheckBox
        AllSwitch_2Label          matlab.ui.control.Label
        AllSwitch_2               matlab.ui.control.ToggleSwitch
        GantryMotorsLabel         matlab.ui.control.Label
        StopButton                matlab.ui.control.StateButton
        Lamp_yMotor               matlab.ui.control.Lamp
        Lamp_z1Motor              matlab.ui.control.Lamp
        Lamp_z2Motor              matlab.ui.control.Lamp
        Lamp_UMotor               matlab.ui.control.Lamp
        USwitchLabel              matlab.ui.control.Label
        USwitch                   matlab.ui.control.Switch
        Lamp_xMotor               matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        Property % Custom properties
        gantry;
        cam;
        joy;
        fid;
        focus;
        gluing;
        dispenser;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gantry = STAGES(2);
            app.joy = JOYSTICK (app.gantry);
            app.fid=FIDUCIALS(1);
            app.cam= CAMERA(5);
        end

        % Value changed function: XSwitch
        function XSwitchValueChanged(app, event)
            value = app.XSwitch.Value;
            switch value
                case 'On'
                    app.XSwitchLabel.BackgroundColor = 'g';
                case 'Off'
                    app.XSwitchLabel.BackgroundColor = 'r';
            end
        end

        % Value changed function: YSwitch
        function YSwitchValueChanged(app, event)
            value = app.YSwitch.Value;
            switch value
                case 'On'
                    app.YSwitchLabel.BackgroundColor = 'g';
                case 'Off'
                    app.YSwitchLabel.BackgroundColor = 'r';
            end
        end

        % Value changed function: Z1Switch
        function Z1SwitchValueChanged(app, event)
            value = app.Z1Switch.Value;
            switch value
                case 'On'
                    app.Z1SwitchLabel.BackgroundColor = 'g';
                case 'Off'
                    app.Z1SwitchLabel.BackgroundColor = 'r';
            end
        end

        % Value changed function: Z2Switch
        function Z2SwitchValueChanged(app, event)
            value = app.Z2Switch.Value;
            switch value
                case 'On'
                    app.Z2SwitchLabel.BackgroundColor = 'g';
                case 'Off'
                    app.Z2SwitchLabel.BackgroundColor = 'r';
            end
        end

        % Value changed function: AllSwitch
        function AllSwitchValueChanged(app, event)
            value = app.AllSwitch.Value;
            switch value
                case 'On'
                    app.AllSwitchLabel.BackgroundColor = 'g';
                case 'Off'
                    app.AllSwitchLabel.BackgroundColor = 'r';
            end
            app.XSwitch.Value = value;
            app.YSwitch.Value = value;
            app.Z1Switch.Value = value;
            app.Z2Switch.Value = value;
            %             drawnow
            %             app.XSwitchValueChanged();
            %             app.YSwitchValueChanged();
            %             app.Z1SwitchValueChanged();
            %             app.Z2SwitchValueChanged();
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            if value == 1
                app.StopButton.BackgroundColor='r';
                app.gantry.MotionAbortAll();
            else
                app.StopButton.BackgroundColor = 'g';
            end
        end

        % Value changed function: ConnectGantryCheckBox
        function ConnectGantryCheckBoxValueChanged(app, event)
            value = app.ConnectGantryCheckBox.Value;
            if value == 1
                app.gantry = STAGES(2);
                app.gantry.Connect;
            else
                app.gantry.Disconnect;
            end
        end

        % Value changed function: ConnectDispenserCheckBox
        function ConnectDispenserCheckBoxValueChanged(app, event)
            value = app.ConnectDispenserCheckBox.Value;
            if value == 1
                
            else
                
            end
        end

        % Value changed function: ConnectJoystickCheckBox
        function ConnectJoystickCheckBoxValueChanged(app, event)
            value = app.ConnectJoystickCheckBox.Value;
            if value == 1
                app.joy = JOYSTICK (app.gantry);
                app.joy.Connect;
            else
                app.joy.Disconnect;
            end
        end

        % Value changed function: ConnectCameraCheckBox
        function ConnectCameraCheckBoxValueChanged(app, event)
            value = app.ConnectCameraCheckBox.Value;
            if value == 1
                app.cam= CAMERA(5);
                app.cam.Connect;
                app.cam.DispCam;
            else
                app.cam.Disconnect;
                imaqreset;
            end
        end

        % Value changed function: AllSwitch_2
        function AllSwitch_2ValueChanged(app, event)
            value = app.AllSwitch_2.Value;
            switch value
                case 'On'
                    app.ConnectGantryCheckBox.Value = 1;
                    app.ConnectJoystickCheckBox.Value = 1;
                    app.ConnectCameraCheckBox.Value = 1;
                    app.ConnectDispenserCheckBox.Value = 1;
                case 'Off'
                    app.ConnectGantryCheckBox.Value = 0;
                    app.ConnectJoystickCheckBox.Value = 0;
                    app.ConnectCameraCheckBox.Value = 0;
                    app.ConnectDispenserCheckBox.Value = 0;
            end
%             app.ConnectGantryCheckBox.ValueChanged();
%             app.ConnectJoystickCheckBox.ValueChanged();
%             app.ConnectCameraCheckBox.ValueChanged();
%             app.ConnectDispenserCheckBox.ValueChanged();
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            if app.gantry.IsConnected ~= 1
                app.ConnectGantryCheckBox = 0;
            end
            if app.joy.JoystickIsReady ~= 1
                app.ConnectJoystickCheckBox = 0;
            end
            
            if app.cam.IsConnected ~= 1
                app.ConnectCameraCheckBox = 0;
            end
            
            if app.gantry.MotorState(1) == 
            
            drawnow limitrate;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.XSwitchLabel = uilabel(app.UIFigure);
            app.XSwitchLabel.Text = 'X';
            app.XSwitch = uiswitch(app.UIFigure, 'slider');
            app.XSwitch.ValueChangedFcn = createCallbackFcn(app, @XSwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gantry')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.ZLim = [-10 100];
            app.YSwitchLabel = uilabel(app.UIFigure);
            app.YSwitchLabel.Text = 'Y';
            app.YSwitch = uiswitch(app.UIFigure, 'slider');
            app.YSwitch.ValueChangedFcn = createCallbackFcn(app, @YSwitchValueChanged, true);
            app.Z1SwitchLabel = uilabel(app.UIFigure);
            app.Z1SwitchLabel.Text = 'Z1';
            app.Z1Switch = uiswitch(app.UIFigure, 'slider');
            app.Z1Switch.ValueChangedFcn = createCallbackFcn(app, @Z1SwitchValueChanged, true);
            app.Z2SwitchLabel = uilabel(app.UIFigure);
            app.Z2SwitchLabel.Text = 'Z2';
            app.Z2Switch = uiswitch(app.UIFigure, 'slider');
            app.Z2Switch.ValueChangedFcn = createCallbackFcn(app, @Z2SwitchValueChanged, true);
            app.AllSwitchLabel = uilabel(app.UIFigure);
            app.AllSwitchLabel.Text = 'All';
            app.AllSwitch = uiswitch(app.UIFigure, 'toggle');
            app.AllSwitch.ValueChangedFcn = createCallbackFcn(app, @AllSwitchValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Motor Current')
            xlabel(app.UIAxes2, {''; ''})
            ylabel(app.UIAxes2, 'mA')
            app.ActionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ActionsButtonGroup.Title = 'Actions';
            app.LocatePetalButton = uitogglebutton(app.ActionsButtonGroup);
            app.LocatePetalButton.Text = 'Locate Petal';
            app.LocatePetalButton.Value = true;
            app.LocateHybridsButton = uitogglebutton(app.ActionsButtonGroup);
            app.LocateHybridsButton.Text = 'Locate Hybrids';
            app.GluedispensingButton = uitogglebutton(app.ActionsButtonGroup);
            app.GluedispensingButton.Text = 'Glue dispensing';
            app.HomingButton = uitogglebutton(app.ActionsButtonGroup);
            app.HomingButton.Text = 'Homing';
            app.MovementButtonGroup = uibuttongroup(app.UIFigure);
            app.MovementButtonGroup.Title = 'Movement';
            app.SimulationButton = uiradiobutton(app.MovementButtonGroup);
            app.SimulationButton.Text = 'Simulation';
            app.SimulationButton.Value = true;
            app.ManualButton = uiradiobutton(app.MovementButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.AutoButton = uiradiobutton(app.MovementButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Limits = [-500 500];
            app.XEditField.ValueDisplayFormat = '%.3f';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Limits = [-500 500];
            app.YEditField.ValueDisplayFormat = '%.3f';
            app.Z1EditFieldLabel = uilabel(app.UIFigure);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField.Limits = [-10 100];
            app.Z1EditField.ValueDisplayFormat = '%.3f';
            app.Z2EditFieldLabel = uilabel(app.UIFigure);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditField.Limits = [-10 100];
            app.Z2EditField.ValueDisplayFormat = '%.3f';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Ø';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.ConnectGantryCheckBox = uicheckbox(app.UIFigure);
            app.ConnectGantryCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConnectGantryCheckBoxValueChanged, true);
            app.ConnectGantryCheckBox.Text = 'Connect Gantry';
            app.ConnectDispenserCheckBox = uicheckbox(app.UIFigure);
            app.ConnectDispenserCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConnectDispenserCheckBoxValueChanged, true);
            app.ConnectDispenserCheckBox.Text = 'Connect Dispenser';
            app.ConnectCameraCheckBox = uicheckbox(app.UIFigure);
            app.ConnectCameraCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConnectCameraCheckBoxValueChanged, true);
            app.ConnectCameraCheckBox.Text = 'Connect Camera';
            app.ConnectJoystickCheckBox = uicheckbox(app.UIFigure);
            app.ConnectJoystickCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConnectJoystickCheckBoxValueChanged, true);
            app.ConnectJoystickCheckBox.Text = 'Connect Joystick';
            app.AllSwitch_2Label = uilabel(app.UIFigure);
            app.AllSwitch_2Label.Text = 'All';
            app.AllSwitch_2 = uiswitch(app.UIFigure, 'toggle');
            app.AllSwitch_2.ValueChangedFcn = createCallbackFcn(app, @AllSwitch_2ValueChanged, true);
            app.GantryMotorsLabel = uilabel(app.UIFigure);
            app.GantryMotorsLabel.Text = 'Gantry Motors';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.Lamp_xMotor = uilamp(app.UIFigure);
            app.Lamp_yMotor = uilamp(app.UIFigure);
            app.Lamp_z1Motor = uilamp(app.UIFigure);
            app.Lamp_z2Motor = uilamp(app.UIFigure);
            app.Lamp_UMotor = uilamp(app.UIFigure);
            app.USwitchLabel = uilabel(app.UIFigure);
            app.USwitchLabel.Text = 'U';
            app.USwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end